classdef AppD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppDesignerSimulink         matlab.ui.Figure
        GauageExampleKnob           matlab.ui.control.Knob
        GauageExampleKnobLabel      matlab.ui.control.Label
        Gain1Slider                 matlab.ui.control.Slider
        Gain1SliderLabel            matlab.ui.control.Label
        GaugeExample                matlab.ui.control.Gauge
        GaugeExampleLabel           matlab.ui.control.Label
        BackgroundColorButtonGroup  matlab.ui.container.ButtonGroup
        LightGreenButton            matlab.ui.control.RadioButton
        LightBlueButton             matlab.ui.control.RadioButton
        LIghtGrayButton             matlab.ui.control.RadioButton
        Digital_IN_2_Switch         matlab.ui.control.RockerSwitch
        Digital_IN_2Label           matlab.ui.control.Label
        Digital_IN_3_Switch         matlab.ui.control.RockerSwitch
        Digital_IN_3Label           matlab.ui.control.Label
        Digital_IN_1_Switch         matlab.ui.control.RockerSwitch
        Digital_IN_1Label           matlab.ui.control.Label
        Digital_IN_0_Switch         matlab.ui.control.RockerSwitch
        Digital_IN_0SwitchLabel     matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       timerObj %timer
    end
    
    methods (Access = private)      
        function getModelValues(app)
            GaugeFromModel =  get_param([bdroot,'/GauageExample'],'Value');
            app.GaugeExample.Value =  str2double(GaugeFromModel);  
        end    
       
    end
    
    methods (Access = public)
        
        function UpdateGUI(app)
            
        end    
    end
         

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Digital_IN_0_Switch.Value = 'OFF';
            set_param([bdroot,'/Digital_IN_0'],'Value', num2str( app.Digital_IN_0_Switch.Value));
            app.Digital_IN_1_Switch.Value = 'OFF';
            set_param([bdroot,'/Digital_IN_1'],'Value', num2str( app.Digital_IN_1_Switch.Value));
            app.Digital_IN_2_Switch.Value = 'OFF';
            set_param([bdroot,'/Digital_IN_2'],'Value', num2str( app.Digital_IN_2_Switch.Value));
            app.Digital_IN_3_Switch.Value = 'OFF';
            set_param([bdroot,'/Digital_IN_3'],'Value', num2str( app.Digital_IN_3_Switch.Value));
            
             app.timerObj = timer('TimerFcn',@(~,~)getModelValues(app), 'Period',0.1, 'ExecutionMode', 'fixedSpacing', 'BusyMode','queue');    
             start(app.timerObj);
        end

        % Value changed function: Digital_IN_0_Switch
        function Digital_IN_0_SwitchValueChanged(app, event)
            value = app.Digital_IN_0_Switch.Value;
            set_param([bdroot,'/Digital_IN_0'],'Value', num2str(value));          
            
        end

        % Value changed function: Digital_IN_1_Switch
        function Digital_IN_1_SwitchValueChanged(app, event)
            value = app.Digital_IN_1_Switch.Value;
            set_param([bdroot,'/Digital_IN_1'],'Value', num2str(value));
        end

        % Value changed function: Digital_IN_2_Switch
        function Digital_IN_2_SwitchValueChanged(app, event)
            value = app.Digital_IN_2_Switch.Value;
            set_param([bdroot,'/Digital_IN_2'],'Value', num2str(value));
        end

        % Value changed function: Digital_IN_3_Switch
        function Digital_IN_3_SwitchValueChanged(app, event)
            value = app.Digital_IN_3_Switch.Value;
            set_param([bdroot,'/Digital_IN_3'],'Value', num2str(value));
        end

        % Selection changed function: BackgroundColorButtonGroup
        function BackgroundColorButtonGroupSelectionChanged(app, event)
            selectedButton = app.BackgroundColorButtonGroup.SelectedObject;
            if (selectedButton == app.LIghtGrayButton)
                app.AppDesignerSimulink.Color = [0.75 0.75 0.75];               
            end
            if (selectedButton == app.LightBlueButton)
                app.AppDesignerSimulink.Color = [0.67 0.85 0.90];
            end
            if (selectedButton == app.LightGreenButton)
                app.AppDesignerSimulink.Color = [0.56 0.93 0.56];
            end
        end

        % Value changing function: Gain1Slider
        function Gain1SliderValueChanging(app, event)
            changingValue = event.Value;
            gainSlider = num2str(changingValue);
            set_param([bdroot,'/Gain1'],'Gain', gainSlider); 
        end

        % Value changing function: GauageExampleKnob
        function GauageExampleKnobValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/GauageExample'],'Value', num2str(changingValue));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AppDesignerSimulink = uifigure('Visible', 'off');
            app.AppDesignerSimulink.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.AppDesignerSimulink);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Digital_IN_0SwitchLabel = uilabel(app.AppDesignerSimulink);
            app.Digital_IN_0SwitchLabel.Text = 'Digital_IN_0';
            app.Digital_IN_0_Switch = uiswitch(app.AppDesignerSimulink, 'rocker');
            app.Digital_IN_0_Switch.Items = {'OFF', 'ON'};
            app.Digital_IN_0_Switch.ValueChangedFcn = createCallbackFcn(app, @Digital_IN_0_SwitchValueChanged, true);
            app.Digital_IN_0_Switch.Value = 'OFF';
            app.Digital_IN_1Label = uilabel(app.AppDesignerSimulink);
            app.Digital_IN_1Label.Text = 'Digital_IN_1';
            app.Digital_IN_1_Switch = uiswitch(app.AppDesignerSimulink, 'rocker');
            app.Digital_IN_1_Switch.Items = {'OFF', 'ON'};
            app.Digital_IN_1_Switch.ValueChangedFcn = createCallbackFcn(app, @Digital_IN_1_SwitchValueChanged, true);
            app.Digital_IN_1_Switch.Value = 'OFF';
            app.Digital_IN_3Label = uilabel(app.AppDesignerSimulink);
            app.Digital_IN_3Label.Text = 'Digital_IN_3';
            app.Digital_IN_3_Switch = uiswitch(app.AppDesignerSimulink, 'rocker');
            app.Digital_IN_3_Switch.Items = {'OFF', 'ON'};
            app.Digital_IN_3_Switch.ValueChangedFcn = createCallbackFcn(app, @Digital_IN_3_SwitchValueChanged, true);
            app.Digital_IN_3_Switch.Value = 'OFF';
            app.Digital_IN_2Label = uilabel(app.AppDesignerSimulink);
            app.Digital_IN_2Label.Text = 'Digital_IN_2';
            app.Digital_IN_2_Switch = uiswitch(app.AppDesignerSimulink, 'rocker');
            app.Digital_IN_2_Switch.Items = {'OFF', 'ON'};
            app.Digital_IN_2_Switch.ValueChangedFcn = createCallbackFcn(app, @Digital_IN_2_SwitchValueChanged, true);
            app.Digital_IN_2_Switch.Value = 'OFF';
            app.BackgroundColorButtonGroup = uibuttongroup(app.AppDesignerSimulink);
            app.BackgroundColorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BackgroundColorButtonGroupSelectionChanged, true);
            app.BackgroundColorButtonGroup.Title = 'Background Color';
            app.LIghtGrayButton = uiradiobutton(app.BackgroundColorButtonGroup);
            app.LIghtGrayButton.Text = 'Light Gray';
            app.LIghtGrayButton.Value = true;
            app.LightBlueButton = uiradiobutton(app.BackgroundColorButtonGroup);
            app.LightBlueButton.Text = 'Light Blue';
            app.LightGreenButton = uiradiobutton(app.BackgroundColorButtonGroup);
            app.LightGreenButton.Text = 'Light Green';
            app.GaugeExampleLabel = uilabel(app.AppDesignerSimulink);
            app.GaugeExampleLabel.Text = 'GaugeExample';
            app.GaugeExample = uigauge(app.AppDesignerSimulink, 'circular');
            app.Gain1SliderLabel = uilabel(app.AppDesignerSimulink);
            app.Gain1SliderLabel.Text = 'Gain1';
            app.Gain1Slider = uislider(app.AppDesignerSimulink);
            app.Gain1Slider.Limits = [0 1];
            app.Gain1Slider.ValueChangingFcn = createCallbackFcn(app, @Gain1SliderValueChanging, true);
            app.GauageExampleKnobLabel = uilabel(app.AppDesignerSimulink);
            app.GauageExampleKnobLabel.Text = 'GauageExample';
            app.GauageExampleKnob = uiknob(app.AppDesignerSimulink, 'continuous');
            app.GauageExampleKnob.ValueChangingFcn = createCallbackFcn(app, @GauageExampleKnobValueChanging, true);
            % Show the figure after all components are created
            app.AppDesignerSimulink.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AppDesignerSimulink)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppDesignerSimulink)
        end
    end
end