classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DisturbeButton                matlab.ui.control.Button
        DisturbeySlider               matlab.ui.control.Slider
        DisturbeySliderLabel          matlab.ui.control.Label
        DisturbeuSlider               matlab.ui.control.Slider
        DisturbeuSliderLabel          matlab.ui.control.Label
        QEditField                    matlab.ui.control.NumericEditField
        QEditFieldLabel               matlab.ui.control.Label
        REditField                    matlab.ui.control.NumericEditField
        REditFieldLabel               matlab.ui.control.Label
        EncodersResetButton           matlab.ui.control.Button
        K_gainsPanel                  matlab.ui.container.Panel
        k_theta1dotEditField          matlab.ui.control.NumericEditField
        k_theta1dotEditFieldLabel     matlab.ui.control.Label
        k_theta0dotEditField          matlab.ui.control.NumericEditField
        k_theta0dotEditFieldLabel     matlab.ui.control.Label
        k_theta1EditField             matlab.ui.control.NumericEditField
        k_theta1EditFieldLabel        matlab.ui.control.Label
        k_theta0EditField             matlab.ui.control.NumericEditField
        k_theta0EditFieldLabel        matlab.ui.control.Label
        ControlEffortSignalEditField  matlab.ui.control.NumericEditField
        ControlEffortSignalEditFieldLabel  matlab.ui.control.Label
        UIAxes5_2                     matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        UpdateTimer
        simout % Description
    end
    
    methods (Access = private)
        
        function UpdateGUI(app)
            OUT=evalin("base",out)
            plot(app.UIAxes, OUT.X.signal1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pause('on')
             app.UpdateTimer = timer("ExecutionMode","fixedRate","Period",0.05, ...
            "BusyMode","queue","TimerFcn",@app.UpdateGUI)
        end

        % Button pushed function: EncodersResetButton
        function EncodersResetButtonPushed(app, event)
            assignin("base","Reset",1);
            pause(0.1);
            assignin("base","Reset",0);
        end

        % Button pushed function: DisturbeButton
        function DisturbeButtonPushed(app, event)
            assignin("base","uDist",app.DisturbeySlider.Value);
            assignin("base","yDist",app.DisturbeuSlider.Value);
            pause(0.1);
            assignin("base","uDist",0);
            assignin("base","yDist",0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'theta0')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'rad')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'theta1')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'rad/s')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'theta0dot')
            xlabel(app.UIAxes3, 't')
            ylabel(app.UIAxes3, 'rad')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'theta1dot')
            xlabel(app.UIAxes4, 't')
            ylabel(app.UIAxes4, 'rad/s')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Syp')
            xlabel(app.UIAxes5, 'w')
            ylabel(app.UIAxes5, 'sens y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5_2 = uiaxes(app.UIFigure);
            title(app.UIAxes5_2, 'Sup')
            xlabel(app.UIAxes5_2, 'w')
            ylabel(app.UIAxes5_2, 'sens x')
            zlabel(app.UIAxes5_2, 'Z')
            app.ControlEffortSignalEditFieldLabel = uilabel(app.UIFigure);
            app.ControlEffortSignalEditFieldLabel.Text = 'Control Effort Signal';
            app.ControlEffortSignalEditField = uieditfield(app.UIFigure, 'numeric');
            app.K_gainsPanel = uipanel(app.UIFigure);
            app.K_gainsPanel.Title = 'K_gains';
            app.k_theta0EditFieldLabel = uilabel(app.K_gainsPanel);
            app.k_theta0EditFieldLabel.Text = 'k_theta0';
            app.k_theta0EditField = uieditfield(app.K_gainsPanel, 'numeric');
            app.k_theta1EditFieldLabel = uilabel(app.K_gainsPanel);
            app.k_theta1EditFieldLabel.Text = 'k_theta1';
            app.k_theta1EditField = uieditfield(app.K_gainsPanel, 'numeric');
            app.k_theta0dotEditFieldLabel = uilabel(app.K_gainsPanel);
            app.k_theta0dotEditFieldLabel.Text = 'k_theta0dot';
            app.k_theta0dotEditField = uieditfield(app.K_gainsPanel, 'numeric');
            app.k_theta1dotEditFieldLabel = uilabel(app.K_gainsPanel);
            app.k_theta1dotEditFieldLabel.Text = 'k_theta1dot';
            app.k_theta1dotEditField = uieditfield(app.K_gainsPanel, 'numeric');
            app.EncodersResetButton = uibutton(app.UIFigure, 'push');
            app.EncodersResetButton.ButtonPushedFcn = createCallbackFcn(app, @EncodersResetButtonPushed, true);
            app.EncodersResetButton.Text = 'Encoders Reset';
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.QEditFieldLabel = uilabel(app.UIFigure);
            app.QEditFieldLabel.Text = 'Q';
            app.QEditField = uieditfield(app.UIFigure, 'numeric');
            app.DisturbeuSliderLabel = uilabel(app.UIFigure);
            app.DisturbeuSliderLabel.Text = 'Disturbe u';
            app.DisturbeuSlider = uislider(app.UIFigure);
            app.DisturbeuSlider.Limits = [-10 10];
            app.DisturbeySliderLabel = uilabel(app.UIFigure);
            app.DisturbeySliderLabel.Text = 'Disturbe y';
            app.DisturbeySlider = uislider(app.UIFigure);
            app.DisturbeySlider.Limits = [-50 50];
            app.DisturbeButton = uibutton(app.UIFigure, 'push');
            app.DisturbeButton.ButtonPushedFcn = createCallbackFcn(app, @DisturbeButtonPushed, true);
            app.DisturbeButton.Text = 'Disturbe';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end